classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AnschluesseButton     matlab.ui.control.Button
        SteckerButton         matlab.ui.control.Button
        ZuschneidenButton     matlab.ui.control.Button
        BildauswaehlenButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Picture % Picture, which gets read in and edited
    end
    
    methods (Access = private, Static)
        
        function Cut(app, pic)
            app.Picture = GeoTransformation.justClipp(pic);
            imshow(app.Picture.image, 'Parent', app.UIAxes);
        end
        
        function Anschluss(app)
            
        end
        
        function Stecker(app)
            
        end
    end
    

    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Button pushed function: AnschluesseButton
        function AnschluesseButtonPushed(app, event)
            
        end

        % Button pushed function: ZuschneidenButton
        function ZuschneidenButtonPushed(app, event)
            app.Cut(app, app.Picture);
        end

        % Button pushed function: SteckerButton
        function SteckerButtonPushed(app, event)
            
        end

        % Button pushed function: BildauswaehlenButton
        function BildauswaehlenButtonPushed(app, event)
            [File_Name, Path_Name] = uigetfile('*.*');
            %axes(app.UIAxes)
            app.Picture = imread(['Images/' File_Name]);
            imshow(app.Picture.image, 'Parent', app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AnschluesseButton = uibutton(app.UIFigure, 'push');
            app.AnschluesseButton.ButtonPushedFcn = createCallbackFcn(app, @AnschluesseButtonPushed, true);
            app.AnschluesseButton.Text = 'Anschluesse';
            app.SteckerButton = uibutton(app.UIFigure, 'push');
            app.SteckerButton.ButtonPushedFcn = createCallbackFcn(app, @SteckerButtonPushed, true);
            app.SteckerButton.Text = 'Stecker';
            app.ZuschneidenButton = uibutton(app.UIFigure, 'push');
            app.ZuschneidenButton.ButtonPushedFcn = createCallbackFcn(app, @ZuschneidenButtonPushed, true);
            app.ZuschneidenButton.Text = 'Zuschneiden';
            app.BildauswaehlenButton = uibutton(app.UIFigure, 'push');
            app.BildauswaehlenButton.ButtonPushedFcn = createCallbackFcn(app, @BildauswaehlenButtonPushed, true);
            app.BildauswaehlenButton.Text = 'Bild auswaehlen';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bild')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end